classdef mainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectFolderButton  matlab.ui.control.Button
        SelectcsvButton     matlab.ui.control.Button
        csvLabel            matlab.ui.control.Label
        folderLabel         matlab.ui.control.Label
        runningLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
                % Open folder selection dialog
            selectedPath = uigetdir('Select a folder');
            
            % Check if a folder was selected
            if isequal(selectedPath, 0)
                app.folderLabel.Text="No folder selected";
            else
                app.folderLabel.Text=['Selected folder: ', selectedPath];
                assignin('base', 'selectedPath', selectedPath); % Pass variable to base workspace
                app.runningLabel.Text="Running...";
                global selectedFile;
                mainScript(selectedFile,selectedPath); % Run the script
                app.runningLabel.Text="Done! Open your selected folder for the results";
            end
            
        end

        % Button pushed function: SelectcsvButton
        function SelectcsvButtonPushed(app, event)
                    % Open file selection dialog
            [filename, filepath] = uigetfile('*.csv', 'Select a CSV file');
            
            % Check if a file was selected
            if isequal(filename, 0)
                app.csvLabel.Text ="No file selected";
            else
                % file parts return:the file path, file name, and file extension
                [~, ~, ext] = fileparts(filename); %the symbol to ignore the 1st 2 returned output
                
                % Check if the selected file has a CSV extension
                if strcmpi(ext, '.csv') %compare
                    % Construct the full file path
                    global selectedFile; 
                    selectedFile = fullfile(filepath, filename);
                    app.csvLabel.Text=['Selected CSV file: ', selectedFile];
                    assignin('base', 'selectedFile', selectedFile); % Pass variable to base workspace
                else
                    app.csvLabel.Text="Selected file is not a CSV";
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.SelectcsvButton = uibutton(app.UIFigure, 'push');
            app.SelectcsvButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcsvButtonPushed, true);
            app.SelectcsvButton.Text = 'Select csv';
            app.csvLabel = uilabel(app.UIFigure);
            app.csvLabel.Text = 'no csv selected';
            app.folderLabel = uilabel(app.UIFigure);
            app.folderLabel.Text = 'no folder selected';
            app.runningLabel = uilabel(app.UIFigure);
            app.runningLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end